classdef AldyGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RobotSpeedSliderLabel         matlab.ui.control.Label
        RobotSpeedSlider              matlab.ui.control.Slider
        RunningLampLabel              matlab.ui.control.Label
        RunningLamp                   matlab.ui.control.Lamp
        JoggingEnabledLampLabel       matlab.ui.control.Label
        JoggingEnabledLamp            matlab.ui.control.Lamp
        eStopButton                   matlab.ui.control.Button
        BeltSpeedSliderLabel          matlab.ui.control.Label
        BeltSpeedSlider               matlab.ui.control.Slider
        ModeSwitchLabel               matlab.ui.control.Label
        ModeSwitch                    matlab.ui.control.ToggleSwitch
        HeavyVolGaugeLabel            matlab.ui.control.Label
        HeavyVolGauge                 matlab.ui.control.NinetyDegreeGauge
        LightVolGaugeLabel            matlab.ui.control.Label
        LightVolGauge                 matlab.ui.control.NinetyDegreeGauge
        Bag1Label                     matlab.ui.control.Label
        TotalMasskgGaugeLabel         matlab.ui.control.Label
        TotalMasskgGauge              matlab.ui.control.LinearGauge
        Bag2Label                     matlab.ui.control.Label
        HeavyVolGauge_2Label          matlab.ui.control.Label
        HeavyVolGauge_2               matlab.ui.control.NinetyDegreeGauge
        LightVolGauge_2Label          matlab.ui.control.Label
        LightVolGauge_2               matlab.ui.control.NinetyDegreeGauge
        TotalMasskgGauge_2Label       matlab.ui.control.Label
        TotalMasskgGauge_2            matlab.ui.control.LinearGauge
        L1Slider                      matlab.ui.control.Slider
        L2Slider                      matlab.ui.control.Slider
        L3Slider                      matlab.ui.control.Slider
        L4Slider                      matlab.ui.control.Slider
        L5Slider                      matlab.ui.control.Slider
        L6Slider                      matlab.ui.control.Slider
        YButton                       matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        YButton_4                     matlab.ui.control.Button
        ZButton                       matlab.ui.control.Button
        ZButton_2                     matlab.ui.control.Button
        RobotTransformLabel           matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        robotTransformY               matlab.ui.control.Label
        robotTransformX               matlab.ui.control.Label
        robotTransformZ               matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        robotTransformPitch           matlab.ui.control.Label
        robotTransformRoll            matlab.ui.control.Label
        robotTransformYaw             matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        L1Button                      matlab.ui.control.Button
        L1Button_2                    matlab.ui.control.Button
        L2Button_2                    matlab.ui.control.Button
        L2Button                      matlab.ui.control.Button
        L3Button_2                    matlab.ui.control.Button
        L3Button                      matlab.ui.control.Button
        L4Button_2                    matlab.ui.control.Button
        L4Button                      matlab.ui.control.Button
        L5Button_2                    matlab.ui.control.Button
        L5Button                      matlab.ui.control.Button
        L6Button_2                    matlab.ui.control.Button
        L6Button                      matlab.ui.control.Button
        LinkJogStepdegEditFieldLabel  matlab.ui.control.Label
        LinkJogStepdegEditField       matlab.ui.control.NumericEditField
        XYZJogStepmmEditFieldLabel    matlab.ui.control.Label
        XYZJogStepmmEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        idle = true; % Description
        eStop = false;
        env;
    end
    
    methods (Access = public)
        
        function app = updateGUI(app, env)
            app.env = env;
            %update gui transform variables from env
            p = env.AldyBaggerBot.robot.model.getpos;
            t = env.AldyBaggerBot.robot.model.fkine(p);
            app.robotTransformX.Text = num2str(t(1, 4));
            app.robotTransformY.Text = num2str(t(2, 4));
            app.robotTransformZ.Text = num2str(t(3, 4));
            rpy = rad2deg(tr2rpy(t));
            app.robotTransformRoll.Text = num2str(rpy(1));
            app.robotTransformPitch.Text = num2str(rpy(2));
            app.robotTransformYaw.Text = num2str(rpy(3));
            
            %update slider positions
            pDegrees = rad2deg(p);
            app.L1Slider.Value = pDegrees(1);
            app.L2Slider.Value = pDegrees(2);
            app.L3Slider.Value = pDegrees(3);
            app.L4Slider.Value = pDegrees(4);
            app.L5Slider.Value = pDegrees(5);
            app.L6Slider.Value = pDegrees(6);
            
            %update status lamps
            if env.idle == true
                app.RunningLamp.Color = [1,0,0];
            else
                app.RunningLamp.Color = [0,1,0];
                app.JoggingEnabledLamp.Color = [1,0,0];
            end
            
        end
        
        function env = updateEnv(app, env)
            env.idle = app.idle;
            if app.eStop == false
                if app.idle == true
                    %jog mode
                    app.JoggingEnabledLamp.Color = [0,1,0];
                    
                    %sliders
                    p = [app.L1Slider.Value, app.L2Slider.Value, app.L3Slider.Value, app.L4Slider.Value, app.L5Slider.Value, app.L6Slider.Value];
                    p = deg2rad(p);
                    env.AldyBaggerBot.robot.model.animate(p);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, env)
            app.env = env;
            %set slider limits
            limits = rad2deg(env.AldyBaggerBot.robot.model.qlim);
            app.L1Slider.Limits = limits(1, :);
            app.L2Slider.Limits = limits(2, :);
            app.L3Slider.Limits = limits(3, :);
            app.L4Slider.Limits = limits(4, :);
            app.L5Slider.Limits = limits(5, :);
            app.L6Slider.Limits = limits(6, :);
        end

        % Button pushed function: eStopButton
        function eStopButtonPushed(app, event)
            if app.eStop == false
                app.eStopButton.BackgroundColor = [1,0,0];
                app.JoggingEnabledLamp.Color = [1,0,0];
                app.eStop = true;
                app.idle = true;
            else
                app.eStopButton.BackgroundColor = [0,1,0];
                app.eStop = false;
            end        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.eStop == false
                app.idle = false;
            end
        end

        % Button pushed function: L1Button
        function L1ButtonPushed(app, event)
            app.L1Slider.Value = app.L1Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L1Button_2
        function L1Button_2Pushed(app, event)
            app.L1Slider.Value = app.L1Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L2Button_2
        function L2Button_2Pushed(app, event)
            app.L2Slider.Value = app.L2Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L2Button
        function L2ButtonPushed(app, event)
            app.L2Slider.Value = app.L2Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L3Button_2
        function L3Button_2Pushed(app, event)
            app.L3Slider.Value = app.L3Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L3Button
        function L3ButtonPushed(app, event)
            app.L3lider.Value = app.L3Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L4Button_2
        function L4Button_2Pushed(app, event)
            app.L4Slider.Value = app.L4Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L4Button
        function L4ButtonPushed(app, event)
            app.L4Slider.Value = app.L4Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L5Button_2
        function L5Button_2Pushed(app, event)
            app.L5Slider.Value = app.L5Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L5Button
        function L5ButtonPushed(app, event)
            app.L5Slider.Value = app.L5Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L6Button_2
        function L6Button_2Pushed(app, event)
            app.L6Slider.Value = app.L6Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L6Button
        function L6ButtonPushed(app, event)
            app.L6Slider.Value = app.L6Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
%             p = app.env.AldyBaggerBot.robot.model.getpos();
%             pose
%             app.env.AldyBaggerBot.robot.model.ikine()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RobotSpeedSliderLabel = uilabel(app.UIFigure);
            app.RobotSpeedSliderLabel.Text = 'Robot Speed';
            app.RobotSpeedSlider = uislider(app.UIFigure);
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.JoggingEnabledLampLabel = uilabel(app.UIFigure);
            app.JoggingEnabledLampLabel.Text = 'Jogging Enabled';
            app.JoggingEnabledLamp = uilamp(app.UIFigure);
            app.eStopButton = uibutton(app.UIFigure, 'push');
            app.eStopButton.ButtonPushedFcn = createCallbackFcn(app, @eStopButtonPushed, true);
            app.eStopButton.Icon = 'eStop_Icon.png';
            app.eStopButton.Text = '';
            app.BeltSpeedSliderLabel = uilabel(app.UIFigure);
            app.BeltSpeedSliderLabel.Text = 'Belt Speed';
            app.BeltSpeedSlider = uislider(app.UIFigure);
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ModeSwitch.Items = {'Normal', 'Programming'};
            app.ModeSwitch.Value = 'Normal';
            app.HeavyVolGaugeLabel = uilabel(app.UIFigure);
            app.HeavyVolGaugeLabel.Text = 'Heavy Vol %';
            app.HeavyVolGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.LightVolGaugeLabel = uilabel(app.UIFigure);
            app.LightVolGaugeLabel.Text = 'Light Vol %';
            app.LightVolGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.LightVolGauge.ScaleDirection = 'counterclockwise';
            app.Bag1Label = uilabel(app.UIFigure);
            app.Bag1Label.Text = 'Bag 1';
            app.TotalMasskgGaugeLabel = uilabel(app.UIFigure);
            app.TotalMasskgGaugeLabel.Text = 'Total Mass (kg)';
            app.TotalMasskgGauge = uigauge(app.UIFigure, 'linear');
            app.TotalMasskgGauge.Limits = [0 15];
            app.TotalMasskgGauge.MajorTicks = [0 5 10 15];
            app.Bag2Label = uilabel(app.UIFigure);
            app.Bag2Label.Text = 'Bag 2';
            app.HeavyVolGauge_2Label = uilabel(app.UIFigure);
            app.HeavyVolGauge_2Label.Text = 'Heavy Vol %';
            app.HeavyVolGauge_2 = uigauge(app.UIFigure, 'ninetydegree');
            app.LightVolGauge_2Label = uilabel(app.UIFigure);
            app.LightVolGauge_2Label.Text = 'Light Vol %';
            app.LightVolGauge_2 = uigauge(app.UIFigure, 'ninetydegree');
            app.LightVolGauge_2.ScaleDirection = 'counterclockwise';
            app.TotalMasskgGauge_2Label = uilabel(app.UIFigure);
            app.TotalMasskgGauge_2Label.Text = 'Total Mass (kg)';
            app.TotalMasskgGauge_2 = uigauge(app.UIFigure, 'linear');
            app.TotalMasskgGauge_2.Limits = [0 15];
            app.TotalMasskgGauge_2.MajorTicks = [0 5 10 15];
            app.L1Slider = uislider(app.UIFigure);
            app.L2Slider = uislider(app.UIFigure);
            app.L3Slider = uislider(app.UIFigure);
            app.L4Slider = uislider(app.UIFigure);
            app.L5Slider = uislider(app.UIFigure);
            app.L6Slider = uislider(app.UIFigure);
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.Text = '+X';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.Text = '+X';
            app.YButton_4 = uibutton(app.UIFigure, 'push');
            app.YButton_4.Text = '-Y';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.Text = '+Z';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.Text = '-Z';
            app.RobotTransformLabel = uilabel(app.UIFigure);
            app.RobotTransformLabel.Text = 'Robot Transform';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.robotTransformY = uilabel(app.UIFigure);
            app.robotTransformY.Text = '0.000';
            app.robotTransformX = uilabel(app.UIFigure);
            app.robotTransformX.Text = '0.000';
            app.robotTransformZ = uilabel(app.UIFigure);
            app.robotTransformZ.Text = '0.000';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P:';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R:';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y:';
            app.robotTransformPitch = uilabel(app.UIFigure);
            app.robotTransformPitch.Text = '0.000';
            app.robotTransformRoll = uilabel(app.UIFigure);
            app.robotTransformRoll.Text = '0.000';
            app.robotTransformYaw = uilabel(app.UIFigure);
            app.robotTransformYaw.Text = '0.000';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.L1Button = uibutton(app.UIFigure, 'push');
            app.L1Button.ButtonPushedFcn = createCallbackFcn(app, @L1ButtonPushed, true);
            app.L1Button.Text = 'L1+';
            app.L1Button_2 = uibutton(app.UIFigure, 'push');
            app.L1Button_2.ButtonPushedFcn = createCallbackFcn(app, @L1Button_2Pushed, true);
            app.L1Button_2.Text = 'L1-';
            app.L2Button_2 = uibutton(app.UIFigure, 'push');
            app.L2Button_2.ButtonPushedFcn = createCallbackFcn(app, @L2Button_2Pushed, true);
            app.L2Button_2.Text = 'L2+';
            app.L2Button = uibutton(app.UIFigure, 'push');
            app.L2Button.ButtonPushedFcn = createCallbackFcn(app, @L2ButtonPushed, true);
            app.L2Button.Text = 'L2-';
            app.L3Button_2 = uibutton(app.UIFigure, 'push');
            app.L3Button_2.ButtonPushedFcn = createCallbackFcn(app, @L3Button_2Pushed, true);
            app.L3Button_2.Text = 'L3+';
            app.L3Button = uibutton(app.UIFigure, 'push');
            app.L3Button.ButtonPushedFcn = createCallbackFcn(app, @L3ButtonPushed, true);
            app.L3Button.Text = 'L3-';
            app.L4Button_2 = uibutton(app.UIFigure, 'push');
            app.L4Button_2.ButtonPushedFcn = createCallbackFcn(app, @L4Button_2Pushed, true);
            app.L4Button_2.Text = 'L4+';
            app.L4Button = uibutton(app.UIFigure, 'push');
            app.L4Button.ButtonPushedFcn = createCallbackFcn(app, @L4ButtonPushed, true);
            app.L4Button.Text = 'L4-';
            app.L5Button_2 = uibutton(app.UIFigure, 'push');
            app.L5Button_2.ButtonPushedFcn = createCallbackFcn(app, @L5Button_2Pushed, true);
            app.L5Button_2.Text = 'L5+';
            app.L5Button = uibutton(app.UIFigure, 'push');
            app.L5Button.ButtonPushedFcn = createCallbackFcn(app, @L5ButtonPushed, true);
            app.L5Button.Text = 'L5-';
            app.L6Button_2 = uibutton(app.UIFigure, 'push');
            app.L6Button_2.ButtonPushedFcn = createCallbackFcn(app, @L6Button_2Pushed, true);
            app.L6Button_2.Text = 'L6+';
            app.L6Button = uibutton(app.UIFigure, 'push');
            app.L6Button.ButtonPushedFcn = createCallbackFcn(app, @L6ButtonPushed, true);
            app.L6Button.Text = 'L6-';
            app.LinkJogStepdegEditFieldLabel = uilabel(app.UIFigure);
            app.LinkJogStepdegEditFieldLabel.Text = 'Link Jog Step (deg):';
            app.LinkJogStepdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkJogStepdegEditField.Value = 5;
            app.XYZJogStepmmEditFieldLabel = uilabel(app.UIFigure);
            app.XYZJogStepmmEditFieldLabel.Text = 'XYZ Jog Step (mm):';
            app.XYZJogStepmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XYZJogStepmmEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AldyGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end